classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FYPHRRRExtractionUIFigure  matlab.ui.Figure
        RRValue                    matlab.ui.control.Label
        RRLabel                    matlab.ui.control.Label
        HRValue                    matlab.ui.control.Label
        HRLabel                    matlab.ui.control.Label
        ReadingSwitch              matlab.ui.control.Switch
        InterferometerButtonGroup  matlab.ui.container.ButtonGroup
        MZIButton                  matlab.ui.control.RadioButton
        SagnacButton               matlab.ui.control.RadioButton
        RRFltPlot                  matlab.ui.control.UIAxes
        HRFltPlot                  matlab.ui.control.UIAxes
        RawPlot                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        switchStatus
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ReadingSwitch
        function ReadingSwitchValueChanged(app, event)
            value = app.ReadingSwitch.Value;
            
            if value == "On"
                app.InterferometerButtonGroup.Enable = 'off';
                app.switchStatus = true;

                % NI DAQ setting
                dq = daq("ni");
                if app.SagnacButton.Value
                    addinput(dq, "cDAQ1Mod1", "ai0", "Voltage");
                else
                    addinput(dq, "cDAQ1Mod1", "ai0", "Voltage");
                    addinput(dq, "cDAQ1Mod1", "ai0", "Voltage");
                    addinput(dq, "cDAQ1Mod1", "ai0", "Voltage");
                end
                dq.Rate = 5000;
                
                % Sample data for function testing
                if app.SagnacButton.Value
                    loadPVDF = readtable('./Data/Sagnac/Lay_Up_without_anything_Test_1_Jason.xlsx','Range','B:B');
                    PVDFdata = table2array(loadPVDF);
                    senRaw = PVDFdata(1:end,1);
                else
                    loadPVDF = readtable('./Data/MZI/Lay_Without_Cushion_On_Ref_Jason.xlsx','Range','C:E');
                    PVDFdata = table2array(loadPVDF);
                end

                % Function loop, puase is required or application will be crashed
                while(app.switchStatus)
                    scanData = read(dq,seconds(30),"OutputFormat","Matrix");
                    if app.SagnacButton.Value
                        [HR,RR,hrFlt,rrFlt] = Sagnac_Extraction(senRaw,false);
                        plot(app.RawPlot,linspace(0,30,150000),senRaw);
                        plot(app.HRFltPlot,linspace(0,30,150000),hrFlt);
                        plot(app.RRFltPlot,linspace(0,30,150000),rrFlt);
                        app.HRValue.Text = string(HR);
                        app.RRValue.Text = string(RR);
                        pause(0.1);
                    else
                        [HR,RR,hrFlt,rrFlt] = MZI_Extraction(PVDFdata,false);
                        plot(app.RawPlot,linspace(0,30,150000),PVDFdata(1:end,1));
                        hold(app.RawPlot,"on");
                        plot(app.RawPlot,linspace(0,30,150000),PVDFdata(1:end,2));
                        plot(app.RawPlot,linspace(0,30,150000),PVDFdata(1:end,3));
                        hold(app.RawPlot,"off");
                        plot(app.HRFltPlot,linspace(0,30,150000-1),hrFlt);
                        plot(app.RRFltPlot,linspace(0,30,150000-1),rrFlt);
                        app.HRValue.Text = string(HR);
                        app.RRValue.Text = string(RR);
                        pause(0.1);
                    end
                end
                
                % Reset all setting after turned off
                app.HRValue.Text = "-";
                app.RRValue.Text = "-";
                cla(app.RawPlot);
                cla(app.HRFltPlot);
                cla(app.RRFltPlot);
                app.InterferometerButtonGroup.Enable = 'on';
            else
                app.switchStatus = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FYPHRRRExtractionUIFigure = uifigure('Visible', 'off');
            app.FYPHRRRExtractionUIFigure.Name = 'FYP HR&RR Extraction';
            app.RawPlot = uiaxes(app.FYPHRRRExtractionUIFigure);
            title(app.RawPlot, 'Raw BCG Diagram')
            xlabel(app.RawPlot, 'Time (s)')
            ylabel(app.RawPlot, 'Voltage (V)')
            zlabel(app.RawPlot, 'Z')
            app.RawPlot.XLim = [0 30];
            app.HRFltPlot = uiaxes(app.FYPHRRRExtractionUIFigure);
            title(app.HRFltPlot, 'HR Filtered BCG Diagram')
            xlabel(app.HRFltPlot, 'Time (s)')
            ylabel(app.HRFltPlot, 'Voltage (V)')
            zlabel(app.HRFltPlot, 'Z')
            app.HRFltPlot.XLim = [0 30];
            app.RRFltPlot = uiaxes(app.FYPHRRRExtractionUIFigure);
            title(app.RRFltPlot, 'RR Filtered BCG Diagram')
            xlabel(app.RRFltPlot, 'Time (s)')
            ylabel(app.RRFltPlot, 'Voltage (V)')
            zlabel(app.RRFltPlot, 'Z')
            app.RRFltPlot.XLim = [0 30];
            app.InterferometerButtonGroup = uibuttongroup(app.FYPHRRRExtractionUIFigure);
            app.InterferometerButtonGroup.Title = 'Interferometer';
            app.SagnacButton = uiradiobutton(app.InterferometerButtonGroup);
            app.SagnacButton.Text = 'Sagnac';
            app.SagnacButton.Value = true;
            app.MZIButton = uiradiobutton(app.InterferometerButtonGroup);
            app.MZIButton.Text = 'MZI';
            app.ReadingSwitch = uiswitch(app.FYPHRRRExtractionUIFigure, 'slider');
            app.ReadingSwitch.ValueChangedFcn = createCallbackFcn(app, @ReadingSwitchValueChanged, true);
            app.HRLabel = uilabel(app.FYPHRRRExtractionUIFigure);
            app.HRLabel.Text = 'HR:';
            app.HRValue = uilabel(app.FYPHRRRExtractionUIFigure);
            app.HRValue.Text = '-';
            app.RRLabel = uilabel(app.FYPHRRRExtractionUIFigure);
            app.RRLabel.Text = 'RR:';
            app.RRValue = uilabel(app.FYPHRRRExtractionUIFigure);
            app.RRValue.Text = '-';
            % Show the figure after all components are created
            app.FYPHRRRExtractionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FYPHRRRExtractionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FYPHRRRExtractionUIFigure)
        end
    end
end